classdef nuc_labeller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        maxqpiSpinner        matlab.ui.control.Spinner
        maxqpiSpinnerLabel   matlab.ui.control.Label
        minqpiSpinner        matlab.ui.control.Spinner
        minqpiSpinnerLabel   matlab.ui.control.Label
        maxdapiSpinner       matlab.ui.control.Spinner
        maxdapiSpinnerLabel  matlab.ui.control.Label
        mindapiSpinner       matlab.ui.control.Spinner
        mindapiSpinnerLabel  matlab.ui.control.Label
        OKButton             matlab.ui.control.Button
        QPICheckBox          matlab.ui.control.CheckBox
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        dapi; 
        qpi;
        detections;
        plot_ax;
        file_num;
        done;
    end
    
    methods (Access = public)
        
        function update_img(app)
            hold(app.UIAxes, 'off')
            
            tmp = mat2gray(app.dapi, [prctile(app.dapi,app.mindapiSpinner.Value,"all") prctile(app.dapi,app.maxdapiSpinner.Value,"all")]);
            imshow(tmp,'Parent',app.UIAxes);
            hold(app.UIAxes, 'on')
            title(app.UIAxes,num2str(app.file_num));
            if app.QPICheckBox.Value
                tmp = mat2gray(app.qpi, [prctile(app.qpi,app.minqpiSpinner.Value,"all") prctile(app.qpi,app.maxqpiSpinner.Value,"all")]);
                imshow(tmp,'Parent',app.UIAxes);
            end   
        end
        
        function update_plot(app)


            try
                delete(app.plot_ax)
            end

%              app.plot_ax = plot(app.UIAxes,app.detections(:,1), app.detections(:,2), '*','Color',[0, 0.4470, 0.7410]);
            app.plot_ax = plot(app.UIAxes,app.detections(:,1), app.detections(:,2), '*','Color','r');

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dapi, qpi, detections, file_num)
            drawnow;
            app.done = 0;
            app.file_num = file_num;
            app.UIFigure.WindowState = 'maximized';
            app.dapi = double(dapi);
            app.qpi = double(qpi);
            app.detections = detections;
            app.update_img()
            app.update_plot()
        end

        % Value changed function: QPICheckBox
        function QPICheckBoxValueChanged(app, event)
            value = app.QPICheckBox.Value;
            app.update_img()
            app.update_plot()
        end

        % Value changed function: maxqpiSpinner
        function maxqpiSpinnerValueChanged(app, event)
            value = app.maxqpiSpinner.Value;
            app.update_img()
            app.update_plot()
        end

        % Value changed function: minqpiSpinner
        function minqpiSpinnerValueChanged(app, event)
            value = app.minqpiSpinner.Value;
            app.update_img()
            app.update_plot()
        end

        % Value changed function: maxdapiSpinner
        function maxdapiSpinnerValueChanged(app, event)
            value = app.maxdapiSpinner.Value;
            app.update_img()
            app.update_plot()
        end

        % Value changed function: mindapiSpinner
        function mindapiSpinnerValueChanged(app, event)
            value = app.mindapiSpinner.Value;
            app.update_img()
            app.update_plot()
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            try 
                if isequal(event.Source.CurrentObject.Parent,app.UIAxes)  
                    object_x = event.Source.CurrentObject.Parent;
                else
                    if isequal(event.Source.CurrentObject.Parent.Parent,app.UIAxes)
                        object_x = event.Source.CurrentObject.Parent.Parent;
                    else
                        return
                    end
                end
            catch
                return
            end
            
                
             position=object_x.CurrentPoint;
             position=[position(1,1),position(1,2)];


             if strcmp(app.UIFigure.SelectionType,'normal')
                 app.detections = [app.detections; position];
             elseif strcmp(app.UIFigure.SelectionType,'alt')
                 [dist,nearest] = min(sqrt((app.detections(:,1) - position(1)).^2  +  (app.detections(:,2) - position(2)).^2)) ;
                 if dist<25
                    app.detections(nearest,:) = [];
                 end
             elseif strcmp(app.UIFigure.SelectionType,'extend')
                 [dist,nearest] = min(sqrt((app.detections(:,1) - position(1)).^2  +  (app.detections(:,2) - position(2)).^2)) ;
                 if dist<25
                    app.detections(nearest,:) = [];
                 end
                 app.detections = [app.detections; position];
             end
             

            app.update_plot()


        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;

            if strcmp(key,'space')
                app.QPICheckBox.Value = ~app.QPICheckBox.Value;
                app.update_img()
                app.update_plot()
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.done = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.QPICheckBox = uicheckbox(app.UIFigure);
            app.QPICheckBox.ValueChangedFcn = createCallbackFcn(app, @QPICheckBoxValueChanged, true);
            app.QPICheckBox.Text = 'QPI';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.mindapiSpinnerLabel = uilabel(app.UIFigure);
            app.mindapiSpinnerLabel.Text = 'min dapi';
            app.mindapiSpinner = uispinner(app.UIFigure);
            app.mindapiSpinner.Step = 0.05;
            app.mindapiSpinner.Limits = [0 100];
            app.mindapiSpinner.ValueChangedFcn = createCallbackFcn(app, @mindapiSpinnerValueChanged, true);
            app.maxdapiSpinnerLabel = uilabel(app.UIFigure);
            app.maxdapiSpinnerLabel.Text = 'max dapi';
            app.maxdapiSpinner = uispinner(app.UIFigure);
            app.maxdapiSpinner.Step = 0.5;
            app.maxdapiSpinner.Limits = [0 100];
            app.maxdapiSpinner.ValueChangedFcn = createCallbackFcn(app, @maxdapiSpinnerValueChanged, true);
            app.maxdapiSpinner.Value = 99.5;
            app.minqpiSpinnerLabel = uilabel(app.UIFigure);
            app.minqpiSpinnerLabel.Text = 'min qpi';
            app.minqpiSpinner = uispinner(app.UIFigure);
            app.minqpiSpinner.Step = 0.5;
            app.minqpiSpinner.Limits = [0 100];
            app.minqpiSpinner.ValueChangedFcn = createCallbackFcn(app, @minqpiSpinnerValueChanged, true);
            app.minqpiSpinner.Value = 0.2;
            app.maxqpiSpinnerLabel = uilabel(app.UIFigure);
            app.maxqpiSpinnerLabel.Text = 'max qpi';
            app.maxqpiSpinner = uispinner(app.UIFigure);
            app.maxqpiSpinner.Step = 0.5;
            app.maxqpiSpinner.Limits = [0 100];
            app.maxqpiSpinner.ValueChangedFcn = createCallbackFcn(app, @maxqpiSpinnerValueChanged, true);
            app.maxqpiSpinner.Value = 99.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nuc_labeller(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end