classdef segmentation_tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        nucmCheckBox                matlab.ui.control.CheckBox
        naprahovatmistocnnCheckBox  matlab.ui.control.CheckBox
        highthSpinner               matlab.ui.control.Spinner
        highthSpinnerLabel          matlab.ui.control.Label
        loadButton                  matlab.ui.control.Button
        saveButton                  matlab.ui.control.Button
        resegmentButton             matlab.ui.control.Button
        sizeshiftLSpinner           matlab.ui.control.Spinner
        sizeshiftLSpinnerLabel      matlab.ui.control.Label
        sizectrlLSpinner            matlab.ui.control.Spinner
        sizectrlLLabel              matlab.ui.control.Label
        redrawButton                matlab.ui.control.Button
        disableshortcutsCheckBox    matlab.ui.control.CheckBox
        difficultySpinner           matlab.ui.control.Spinner
        difficultySpinnerLabel      matlab.ui.control.Label
        qualitySpinner              matlab.ui.control.Spinner
        qualitySpinnerLabel         matlab.ui.control.Label
        NameLabel                   matlab.ui.control.Label
        noteEditField               matlab.ui.control.EditField
        noteEditFieldLabel          matlab.ui.control.Label
        centroidsCheckBox           matlab.ui.control.CheckBox
        undoButton                  matlab.ui.control.Button
        sizeRSpinner                matlab.ui.control.Spinner
        sizeRLabel                  matlab.ui.control.Label
        sizeLSpinner                matlab.ui.control.Spinner
        lowthSpinner                matlab.ui.control.Spinner
        lowthSpinnerLabel           matlab.ui.control.Label
        sizeLLabel                  matlab.ui.control.Label
        contoursspaceCheckBox       matlab.ui.control.CheckBox
        splittresholdSpinner        matlab.ui.control.Spinner
        splittresholdSpinnerLabel   matlab.ui.control.Label
        minintensitySpinner         matlab.ui.control.Spinner
        minintensitySpinnerLabel    matlab.ui.control.Label
        maxintensitySpinner         matlab.ui.control.Spinner
        maxintensitySpinnerLabel    matlab.ui.control.Label
        OKButton                    matlab.ui.control.Button
        removefillcellrButton       matlab.ui.control.Button
        drawcolorButtonGroup        matlab.ui.container.ButtonGroup
        Darkred8Button              matlab.ui.control.RadioButton
        Cyan7Button                 matlab.ui.control.RadioButton
        Orange6Button               matlab.ui.control.RadioButton
        Backround9Button            matlab.ui.control.RadioButton
        Auto0Button                 matlab.ui.control.RadioButton
        Magenta5Button              matlab.ui.control.RadioButton
        Yellow4Button               matlab.ui.control.RadioButton
        Blue3Button                 matlab.ui.control.RadioButton
        Green2Button                matlab.ui.control.RadioButton
        Red1Button                  matlab.ui.control.RadioButton
        drawdButton                 matlab.ui.control.Button
        joinjButton                 matlab.ui.control.Button
        splitsButton                matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        img_data
        img
        orig_size
        colormap_cells
        img_orig_data
        resize_size
        fg
        color_imgs
        color_ind_img
        contours
        contour_line_width
        centroids
        click_on
        drawcklick
        value
        history_color_ind_img
        history_spiner_values
        history_pointer
        history_length
        img_name
        user_name
        conn
        mass_threshold
        control_pressed
        shift_pressed
        hole_area_threshold
        start_time
        area_threshold
        nuc_data
    end
    
    methods (Access = public)
        

        function update_segmentation(app)
            app.get_segmentation();
%             app.mass_threshold=100;
%             tLo=app.splittresholdSpinner.Value;
%             tHi=app.lowthSpinner.Value;
%             hole_area_threshold=200;
%             
%             segmentaion=hysteresis_thresh(app.img_data,tLo,tHi);
%             
%             segm_old=app.color_ind_img>0;
%             segm_old=imerode(segm_old,strel('disk',5));
%             
%             tmp= imimposemin(-app.img_data, segm_old);
%             
%             tmp=watershed(tmp,app.conn)>0;
%             
%             segmentaion=segmentaion.*tmp;
%             
%             segmentaion=mass_filt(segmentaion,app.img_data,app.mass_threshold,app.conn);
%             segmentaion=~bwareafilt(~segmentaion,[hole_area_threshold,Inf],app.conn);
% 
%             segmentaion=colorize_notouchingsamecolor(segmentaion,app.conn);
%             
%             app.color_ind_img=segmentaion;
% 
%             drawnow;
            
        end


        function get_segmentation(app)
            
            
            tLo=app.lowthSpinner.Value;
            tHi=app.highthSpinner.Value;
            
            
            app.hole_area_threshold=100;
            app.area_threshold=app.hole_area_threshold;

            if app.naprahovatmistocnnCheckBox.Value==0
    
                name_mask=app.img_name;
%                 name_mask = replace(name_mask,'_QPI.tiff','_mask.tiff');
%                 name_mask = replace(name_mask,'_QPI.tiff','_mask_noremove.tiff');
%                 name_mask = replace(name_mask,'_QPI.tiff','_mask_noremove2.tiff');
%                 name_mask = replace(name_mask,'nuc_for_labeling','cell_prediction_1');
%                 mask=imread(name_mask);

                name_mask = replace(name_mask,'_QPI.tiff','_predicted_mask.tif');
                name_mask = replace(name_mask,'nuc_for_labeling\sample3','predicted2');
                mask = imread(name_mask)>0;
                mask = conn4to8(mask);
                
                
%                 centers=mask==2;
%         
%         
%                 centers=~bwareafilt(~centers,[100,Inf]);
%                 
%                 
%                 d=bwdist(~centers);
%                 d=-1*imhmax(d,app.splittresholdSpinner.Value);
%                 w=watershed(d)>0;
%                 centers=w&centers;
%                 
%             
%                 centers=bwareafilt(centers,[70,Inf]);
%                 
%             
%             
%                 
%                      
%                 r=3;
%                 [x,y]=meshgrid(-r:r,-r:r);
%                 s=sqrt(x.^2+y.^2);
%                 s=s<=r;
%                 
%                 
%                 whole=imclose(mask>0,s);
%                 
%                 
%                 whole=~bwareafilt(~whole,[app.hole_area_threshold,Inf]);
%             
%                 whole=bwareafilt(whole,[app.hole_area_threshold,Inf]);
%                 
%                 
%                 
%                 d = bwdistgeodesic(whole,centers);
%                 d(isnan(d))=99999999;
%                 w=watershed(d)>0;
                segmentaion = mask;
                
            else
                
                 [segmentaion,~]=segm_qpi(app.img_data,tLo+0.05,tHi,app.area_threshold,app.hole_area_threshold,tLo,1);
                 drawnow;
            end


            segmentaion=colorize_notouchingsamecolor(segmentaion,app.conn);
            
            app.color_ind_img=segmentaion;

            drawnow;
        end

        
        function redraw_contours(app,varargin)
            
            N=size(app.colormap_cells,1);
            hold(app.UIAxes, 'on')
            
            if app.contoursspaceCheckBox.Value
                for k=1:length(app.contours)
                    c=app.contours(k);
                    delete(c)
                end

                    
                app.contours={};
                for k=1:N
                    tmp=visboundaries(app.UIAxes,app.color_ind_img==k,'Color',app.colormap_cells(k,:),'EnhanceVisibility',0,'LineWidth',app.contour_line_width);
                    app.contours=[app.contours,tmp];
                end
            else

                for k=1:length(app.contours)
                    c=app.contours(k);
                    delete(c)
                end

            end
            if app.centroidsCheckBox.Value
                try
                   
                    delete(app.centroids)
                end
                L=bwlabel(app.color_ind_img>0,app.conn);
                cents=regionprops(L,app.img_data,'WeightedCentroid');
                cents=cat(1,cents(:).WeightedCentroid);
                
                app.centroids=plot(app.UIAxes,cents(:,1),cents(:,2),'b*');
                
            else
                try
                   
                    delete(app.centroids)
                end
            end
            
            
            hold(app.UIAxes, 'off')
            
            if isempty(varargin)
                app.update_hitory();
            end
        end
        
        function update_hitory(app)
            
            app.history_pointer=app.history_pointer+1;
            
            if app.history_pointer>app.history_length
                
                app.history_pointer=1;
            end
            
            k=app.history_pointer;
            
            app.history_color_ind_img(:,:,k)=app.color_ind_img;
                
            app.history_spiner_values(:,k)=[app.lowthSpinner.Value,app.splittresholdSpinner.Value];
            
            
        end


        function nuc_to_img(app)
            if app.nucmCheckBox.Value==0
                app.img.CData =  mat2gray(app.img_data,[app.minintensitySpinner.Value,app.maxintensitySpinner.Value]);
            else
                app.img.CData = mat2gray(app.nuc_data, [prctile(app.nuc_data,0.2,"all") prctile(app.nuc_data,99.5,"all")]);
            end


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img_name, user_name)
            app.start_time=tic;
            
            
            app.history_length=25;
            
            app.img_name=img_name;
            
            app.NameLabel.Text=user_name;
            
            app.img_name=img_name;
            
            app.conn=8;
            
            app.control_pressed=0;
            app.shift_pressed=0; 
            
            img=double(imread(img_name));

            lam = 0.65;
            alpha = 0.18;
            img = img * lam / (2 * pi * alpha);
            
            app.img_data=img;

            app.nuc_data = double(imread(replace(img_name,'_QPI.tiff','_DAPI.tiff')));
            
            app.user_name=user_name;
            
            drawnow;
            app.UIFigure.WindowState = 'maximized';
            
            app.img_orig_data=img;
            
            app.click_on=1;
            app.drawcklick=0;
            

            app.colormap_cells=[1 0 0;0 1 0;0 0 1;0.8314 0.8314 0.0588;1 0 1;1,0.5,0;0.00,1.00,1.00;0.45,0.00,0.08];
            app.contour_line_width=0.1;

            app.img=imshow(mat2gray(app.img_data,[app.minintensitySpinner.Value,app.maxintensitySpinner.Value]),'Parent',app.UIAxes);
            title(app.UIAxes,app.img_name,"FontSize",11)
            
            
            app.get_segmentation();
            
            app.history_spiner_values=zeros(2,app.history_length);
            
            app.history_color_ind_img=zeros(size(app.color_ind_img,1),size(app.color_ind_img,2),app.history_length);
            app.history_pointer=1;
            
            for k=1:app.history_length
                app.history_color_ind_img(:,:,k)=app.color_ind_img;
                
                app.history_spiner_values(:,k)=[app.lowthSpinner.Value,app.splittresholdSpinner.Value];


            end


            app.redraw_contours()
            
            waitfor(app.UIFigure, 'close');

        end

        % Value changed function: lowthSpinner, splittresholdSpinner
        function SpinnerValueChanged(app, event)
            app.update_segmentation();
            
            app.redraw_contours()
        end

        % Callback function
        function showmeshxCheckBoxValueChanged(app, event)

            app.color_img_lines.AlphaData= double(app.showmeshxCheckBox.Value)*app.lines_alpha*double(app.lines_data);
            
        end

        % Value changed function: contoursspaceCheckBox
        function contoursspaceCheckBoxValueChanged(app, event)
            app.redraw_contours()
            
        end

        % Button pushed function: drawdButton
        function drawdButtonPushed(app, event)
           app.click_on=0;
            
            
            switch app.drawcolorButtonGroup.SelectedObject
                    case app.Red1Button
                        value=1;
                    case app.Green2Button
                        value=2;
                    case app.Blue3Button
                        value=3;
                    case app.Yellow4Button
                        value=4;
                    case app.Magenta5Button
                        value=5;
                    case app.Orange6Button
                        value=6;
                    case app.Cyan7Button
                        value=7;
                    case app.Darkred8Button
                        value=8;
                    case app.Backround9Button
                        value=0;    
                    case app.Auto0Button
                        value=9;    
                        
             end
            
            
            h=drawfreehand(app.UIAxes);
            app.click_on=1;
            draw_image=h.createMask(app.img);
            if value==9
                position=round(app.UIAxes.CurrentPoint(1,[2,1]));
                value=app.color_ind_img(position(1),position(2));
            end
            delete(h);
            app.color_ind_img(draw_image)=value;
            
            draw_image=imdilate(draw_image,true(3));
            app.color_ind_img(app.color_ind_img~=value &draw_image)=0;
            
            
            app.redraw_contours()
        end

        % Button pushed function: joinjButton
        function joinjButtonPushed(app, event)
            app.click_on=0;
            h=drawfreehand(app.UIAxes,'Closed',0);
            app.click_on=1;
            draw_image=h.createMask(app.img);
            delete(h);
            L=bwlabel(app.color_ind_img>0,app.conn);
            u=unique(L(draw_image))';
            u(u==0)=[];
            mask=false(size(app.img_data));
            for k=u
                mask(L==k)=1;
                
            end
            mask=imclose(mask,ones(3));
            L(mask)=1;
            L=L>0;
            app.color_ind_img=colorize_notouchingsamecolor(L,app.conn);
            
            app.redraw_contours()
            
            
        end

        % Button pushed function: splitsButton
        function splitsButtonPushed(app, event)
            app.click_on=0;
            h=drawfreehand(app.UIAxes,'Closed',0);
            app.click_on=1;
            draw_image=h.createMask(app.img);
%             P00 = h.getPosition;
            delete(h);
            
            [x,y]=find(draw_image);
            P00=[y,x];
            
            F = false(size(draw_image));

            % P0 = unique(round(P00),'rows');
            P00=P00+(rand(size(P00))-0.6);
            [~,ia1,~]=unique(round(P00(:,1)*10)/10,'rows');
            [~,ia2,~]=unique(round(P00(:,2)*10)/10,'rows');
            vektor=zeros(1,size(P00,1));
            vektor(ia1)=vektor(ia1)+1;
            vektor(ia2)=vektor(ia2)+2;
            P0=P00(find(vektor>0),:);
            
            D = round([0; cumsum(sum(abs(diff(P0)),2))]);
            [D,ia1,~]=unique(D,'rows');
            P0=P0(ia1,:);
            P = interp1(D,P0,D(1):.3:D(end)); % ...to close the gaps
            
%             P = unique(round(P),'rows');
            P = round(P);
            
            P(P<1)=1;
            P1=P(:,1);
            P2=P(:,2);
            P1(P1>size(draw_image,2))=size(draw_image,2);
            P2(P2>size(draw_image,1))=size(draw_image,1);
            S = sub2ind(size(draw_image),P2,P1);
            
            
            F(S) = true;
            s=zeros(3);
            s(2,2)=1;
            s(1,2)=1;
%             s(2,1)=1;
            F=imdilate(F,s);
            
            draw_image=F;


%             tmp=app.color_ind_img(draw_image);
%             tmp=tmp(tmp>0);
%             draw_image=draw_image&(app.color_ind_img==mode(tmp));
            
            del_img=zeros(size(app.color_ind_img),'logical');
            L=bwlabel(app.color_ind_img,app.conn);
            tmp=app.color_ind_img>0;
            tmp(draw_image>0)=0;
            L2=bwlabel(tmp,app.conn);
            N=max(L(:));
            for k=1:N
                u=unique(L2(L==k));
                u(u==0)=[];
                if length(u)>1
                    del_img(L==k)=1;
                end
            end
            
            draw_image=draw_image&del_img;
            app.color_ind_img(draw_image)=0;
            
            app.color_ind_img=colorize_notouchingsamecolor(app.color_ind_img>0,app.conn);
            
            app.redraw_contours()
        end

        % Button pushed function: removefillcellrButton
        function removefillcellrButtonPushed(app, event)
            app.click_on=0;
            h = drawpoint(app.UIAxes);
            app.click_on=1;
            pos=h.Position;
            delete(h)
            
            L=bwlabel(app.color_ind_img>0,app.conn);
            
            k=L(round(pos(2)),round(pos(1)));
            
            
             switch app.drawcolorButtonGroup.SelectedObject
                    case app.Red1Button
                        app.value=1;
                    case app.Green2Button
                        app.value=2;
                    case app.Blue3Button
                        app.value=3;
                    case app.Yellow4Button
                        app.value=4;
                    case app.Magenta5Button
                        app.value=5;
                    case app.Orange6Button
                        app.value=6;
                    case app.Cyan7Button
                        app.value=7;
                    case app.Darkred8Button
                        app.value=8;
                    case app.Backround9Button
                        app.value=0;    
                    case app.Auto0Button
                        app.value=9;    
                                
                 end
                 
                if app.value==9
                    app.value=0;
                end


            app.color_ind_img(L==k)=app.value;
            
            
            app.redraw_contours()
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)


            if app.drawcklick>0
                if app.click_on
                     
                     position=round(app.UIAxes.CurrentPoint(1,[2,1]));
                     
                 if position(1)>size(app.color_ind_img,1)||position(2)>size(app.color_ind_img,2)||position(1)<0||position(2)<0
                    return
                 end


                    if app.drawcklick==1
                          r=app.sizeLSpinner.Value;
                    elseif app.drawcklick==3
                          r=app.sizeRSpinner.Value;   
                    elseif app.drawcklick==2
                         r=app.sizectrlLSpinner.Value; 
                    elseif app.drawcklick==4
                         r=app.sizeshiftLSpinner.Value;
                    end
                    [x,y]=meshgrid(-r:r,-r:r);
                    s=sqrt(x.^2+y.^2);
                    s=s<=r;
                    
                       
                    draw_image=false(size(app.img_data));
                    draw_image(position(1),position(2))=1;
                    draw_image=imdilate(draw_image, s);
                    app.color_ind_img(draw_image)=app.value;
                    
                    draw_image=imdilate(draw_image,true(3));
                    app.color_ind_img(app.color_ind_img~=app.value &draw_image)=0;
                    
                     
                    app.redraw_contours();
                end
                
            end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)

            app.drawcklick=1;
                if strcmp(event.Source.SelectionType,'normal')
                    app.drawcklick=1;
                        
                elseif strcmp(event.Source.SelectionType,'alt')
                    
                    if app.control_pressed
                    
                        app.drawcklick=2;

                        
                    else
                        
                        app.drawcklick=3;
                    
                    
                    end
                    
                elseif strcmp(event.Source.SelectionType,'extend')
                    if app.shift_pressed
                        
                        app.drawcklick=4;
                    end
                 end
                 
                 position=round(app.UIAxes.CurrentPoint(1,[2,1]));
                 if position(1)>size(app.color_ind_img,1)||position(2)>size(app.color_ind_img,2)||position(1)<0||position(2)<0
                    return
                 end
                 
                 switch app.drawcolorButtonGroup.SelectedObject
                    case app.Red1Button
                        app.value=1;
                    case app.Green2Button
                        app.value=2;
                    case app.Blue3Button
                        app.value=3;
                    case app.Yellow4Button
                        app.value=4;
                    case app.Magenta5Button
                        app.value=5;
                    case app.Orange6Button
                        app.value=6;
                    case app.Cyan7Button
                        app.value=7;
                    case app.Darkred8Button
                        app.value=8;
                    case app.Backround9Button
                        app.value=0;    
                    case app.Auto0Button
                        app.value=9;    
                                
                 end
                 
                if app.value==9
                    app.value=app.color_ind_img(position(1),position(2));
                end
   
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.drawcklick=0;
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            if app.disableshortcutsCheckBox.Value==0


            key = event.Key;
            switch key
                case 'space'
                    app.contoursspaceCheckBox.Value=~app.contoursspaceCheckBox.Value;
                    app.redraw_contours();
                case 'uparrow'
                    app.sizeLSpinner.Value=app.sizeLSpinner.Value+1;
                case 'downarrow'
                    app.sizeLSpinner.Value=app.sizeLSpinner.Value-1;
                case 'd'
                    app.drawdButtonPushed();
                case 'j'
                    app.joinjButtonPushed();
                case 's'
                    app.splitsButtonPushed() 
                case 'r'
                    app.removefillcellrButtonPushed();
                case 'numpad1'
                    app.Red1Button.Value=1;
                case 'numpad2'
                    app.Green2Button.Value=1;  
                case 'numpad3'
                    app.Blue3Button.Value=1;
                case 'numpad4'
                    app.Yellow4Button.Value=1;  
                case 'numpad5'
                    app.Magenta5Button.Value=1;
                case 'numpad6'
                    app.Orange6Button.Value=1;
                case 'numpad7'
                    app.Cyan7Button.Value=1;
                case 'numpad8'
                    app.Darkred8Button.Value=1;
                case 'numpad9'
                    app.Backround9Button.Value=1;
                case 'numpad0'
                    app.Auto0Button.Value=1;
                case 'control'
                    app.control_pressed=1;
                case 'shift'
                    app.shift_pressed=1;
                case 'm'
                    app.nucmCheckBox.Value = ~app.nucmCheckBox.Value;
                    app.nuc_to_img();
                    
            end
            
            end
        end

        % Button pushed function: undoButton
        function undoButtonPushed(app, event)
            
            
            app.history_pointer=app.history_pointer-1;
            
            if app.history_pointer==0
                
                app.history_pointer=app.history_length;
            end
            
            k=app.history_pointer;
            
            app.color_ind_img=app.history_color_ind_img(:,:,k);
                
            app.lowthSpinner.Value=app.history_spiner_values(1,k);
            app.splittresholdSpinner.Value=app.history_spiner_values(2,k);

            
            app.redraw_contours('no_update')

            
        end

        % Value changed function: centroidsCheckBox
        function centroidsCheckBoxValueChanged(app, event)
            app.redraw_contours()
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            

            if app.qualitySpinner.Value==0||app.difficultySpinner.Value==0
                warndlg('zadej kvalitu šulku')
                return
            end


            d=datestr(now);
            
            d_name = strrep(strrep(strrep(d,' ','_'),':','_'),'-','_');

            tmp=app.img_name;
            tmp=strrep(tmp,'.tiff',['_res_' d_name '.png']);
            imwrite(uint8(app.color_ind_img*25),tmp);
            
            
%             tmp=app.img_name;
%             tmp=strrep(tmp,'.tiff',['_control_' d_name  '.png']);
%             saveas(app.UIAxes,tmp)
            
            tmp=app.img_name;
            tmp=strrep(tmp,'.tiff',['_res_' d_name '.txt']);
            fileID=fopen(tmp,'w');
            fprintf(fileID, [app.user_name '\n']);
            fprintf(fileID,[d '\n']);
            fprintf(fileID,['note: ' app.noteEditField.Value '\n']);
            fprintf(fileID,['quality/difficulty: ' num2str(app.qualitySpinner.Value) '/' num2str(app.difficultySpinner.Value) '\n']);
            fprintf(fileID,['quduration: ' num2str(toc(app.start_time)) '\n']);
            fclose(fileID);
            
            
            delete(app.UIFigure)
            
        end

        % Button pushed function: redrawButton
        function redrawButtonPushed(app, event)
%             tmp=mass_filt(app.color_ind_img>0,app.img_data,app.mass_threshold,app.conn);
            tmp=app.color_ind_img>0;
            tmp=~bwareafilt(~tmp,[app.hole_area_threshold,Inf],app.conn);
            tmp=bwareafilt(tmp,[app.hole_area_threshold,Inf],app.conn);

            app.color_ind_img=colorize_notouchingsamecolor(tmp,app.conn);
            
            hold(app.UIAxes, 'off')
            app.img=imshow(mat2gray(app.img_data,[app.minintensitySpinner.Value,app.maxintensitySpinner.Value]),'Parent',app.UIAxes);
            title(app.UIAxes,app.img_name,"FontSize",11)
            hold(app.UIAxes, 'on')
            
            app.redraw_contours();
        end

        % Value changed function: maxintensitySpinner, minintensitySpinner
        function SpinnerValueChanged2(app, event)
%             app.redraw()
          app.img.CData=  mat2gray(app.img_data,[app.minintensitySpinner.Value,app.maxintensitySpinner.Value]);
            
        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)
            key = event.Key;
            switch key
                case 'control'
                    app.control_pressed=0;
                case 'shift'
                    app.shift_pressed=0;   
            end
                
        end

        % Button pushed function: resegmentButton
        function resegmentButtonPushed(app, event)
            app.get_segmentation();
            app.redraw_contours()
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            [file,path] = uiputfile('../tmp.png');
            if isequal(file,0)
                
            else
                imwrite(uint8(app.color_ind_img*25),[path filesep file]);
            end
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            [file,path] = uigetfile('../tmp.png');
            if isequal(file,0)
                
            else
                app.color_ind_img=imread([path filesep file]);
            end
            
            pause(0.5)
            drawnow;
            
            hold(app.UIAxes, 'off')
            app.img=imshow(mat2gray(app.img_data,[app.minintensitySpinner.Value,app.maxintensitySpinner.Value]),'Parent',app.UIAxes);
            title(app.UIAxes,app.img_name,"FontSize",11)
            hold(app.UIAxes, 'on')
            
            app.redraw_contours()
        end

        % Value changed function: naprahovatmistocnnCheckBox
        function naprahovatmistocnnCheckBoxValueChanged(app, event)
            if app.naprahovatmistocnnCheckBox.Value==1
                app.splittresholdSpinner.Visible=0;
                app.lowthSpinner.Visible=1;
                app.highthSpinner.Visible=1;
            else
                app.splittresholdSpinner.Visible=1;
                app.lowthSpinner.Visible=0;
                app.highthSpinner.Visible=0;
            end
            app.get_segmentation();
            app.redraw_contours()
        end

        % Value changed function: nucmCheckBox
        function nucmCheckBoxValueChanged(app, event)
            value = app.nucmCheckBox.Value;
            app.nuc_to_img();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.splitsButton = uibutton(app.UIFigure, 'push');
            app.splitsButton.ButtonPushedFcn = createCallbackFcn(app, @splitsButtonPushed, true);
            app.splitsButton.Text = 'split (s)';
            app.joinjButton = uibutton(app.UIFigure, 'push');
            app.joinjButton.ButtonPushedFcn = createCallbackFcn(app, @joinjButtonPushed, true);
            app.joinjButton.Text = 'join (j)';
            app.drawdButton = uibutton(app.UIFigure, 'push');
            app.drawdButton.ButtonPushedFcn = createCallbackFcn(app, @drawdButtonPushed, true);
            app.drawdButton.Text = 'draw (d)';
            app.drawcolorButtonGroup = uibuttongroup(app.UIFigure);
            app.drawcolorButtonGroup.Title = 'draw color';
            app.Red1Button = uiradiobutton(app.drawcolorButtonGroup);
            app.Red1Button.Text = 'Red (1)';
            app.Green2Button = uiradiobutton(app.drawcolorButtonGroup);
            app.Green2Button.Text = 'Green (2)';
            app.Blue3Button = uiradiobutton(app.drawcolorButtonGroup);
            app.Blue3Button.Text = 'Blue (3)';
            app.Yellow4Button = uiradiobutton(app.drawcolorButtonGroup);
            app.Yellow4Button.Text = 'Yellow (4)';
            app.Magenta5Button = uiradiobutton(app.drawcolorButtonGroup);
            app.Magenta5Button.Text = 'Magenta (5)';
            app.Auto0Button = uiradiobutton(app.drawcolorButtonGroup);
            app.Auto0Button.Text = 'Auto (0)';
            app.Auto0Button.Value = true;
            app.Backround9Button = uiradiobutton(app.drawcolorButtonGroup);
            app.Backround9Button.Text = 'Backround (9)';
            app.Orange6Button = uiradiobutton(app.drawcolorButtonGroup);
            app.Orange6Button.Text = 'Orange (6)';
            app.Cyan7Button = uiradiobutton(app.drawcolorButtonGroup);
            app.Cyan7Button.Text = 'Cyan (7)';
            app.Darkred8Button = uiradiobutton(app.drawcolorButtonGroup);
            app.Darkred8Button.Text = 'Dark red (8)';
            app.removefillcellrButton = uibutton(app.UIFigure, 'push');
            app.removefillcellrButton.ButtonPushedFcn = createCallbackFcn(app, @removefillcellrButtonPushed, true);
            app.removefillcellrButton.Text = 'remove/fill cell (r)';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.maxintensitySpinnerLabel = uilabel(app.UIFigure);
            app.maxintensitySpinnerLabel.Text = 'max intensity';
            app.maxintensitySpinner = uispinner(app.UIFigure);
            app.maxintensitySpinner.Step = 0.1;
            app.maxintensitySpinner.Limits = [-5 5];
            app.maxintensitySpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged2, true);
            app.maxintensitySpinner.Value = 1.5;
            app.minintensitySpinnerLabel = uilabel(app.UIFigure);
            app.minintensitySpinnerLabel.Text = 'min intensity';
            app.minintensitySpinner = uispinner(app.UIFigure);
            app.minintensitySpinner.Step = 0.1;
            app.minintensitySpinner.Limits = [-5 5];
            app.minintensitySpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged2, true);
            app.minintensitySpinner.Value = -0.3;
            app.splittresholdSpinnerLabel = uilabel(app.UIFigure);
            app.splittresholdSpinnerLabel.Text = 'split treshold';
            app.splittresholdSpinner = uispinner(app.UIFigure);
            app.splittresholdSpinner.Limits = [0 50];
            app.splittresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.splittresholdSpinner.Value = 7;
            app.contoursspaceCheckBox = uicheckbox(app.UIFigure);
            app.contoursspaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @contoursspaceCheckBoxValueChanged, true);
            app.contoursspaceCheckBox.Text = 'contours (space)';
            app.contoursspaceCheckBox.Value = true;
            app.sizeLLabel = uilabel(app.UIFigure);
            app.sizeLLabel.Text = 'size L';
            app.lowthSpinnerLabel = uilabel(app.UIFigure);
            app.lowthSpinnerLabel.Text = 'low th';
            app.lowthSpinner = uispinner(app.UIFigure);
            app.lowthSpinner.Step = 0.02;
            app.lowthSpinner.Limits = [-5 5];
            app.lowthSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.sizeLSpinner = uispinner(app.UIFigure);
            app.sizeLSpinner.Limits = [1 1000];
            app.sizeLSpinner.Value = 8;
            app.sizeRLabel = uilabel(app.UIFigure);
            app.sizeRLabel.Text = 'size R';
            app.sizeRSpinner = uispinner(app.UIFigure);
            app.sizeRSpinner.Limits = [1 1000];
            app.sizeRSpinner.Value = 16;
            app.undoButton = uibutton(app.UIFigure, 'push');
            app.undoButton.ButtonPushedFcn = createCallbackFcn(app, @undoButtonPushed, true);
            app.undoButton.Text = 'undo';
            app.centroidsCheckBox = uicheckbox(app.UIFigure);
            app.centroidsCheckBox.ValueChangedFcn = createCallbackFcn(app, @centroidsCheckBoxValueChanged, true);
            app.centroidsCheckBox.Text = 'centroids';
            app.noteEditFieldLabel = uilabel(app.UIFigure);
            app.noteEditFieldLabel.Text = 'note';
            app.noteEditField = uieditfield(app.UIFigure, 'text');
            app.NameLabel = uilabel(app.UIFigure);
            app.NameLabel.Text = 'Name';
            app.qualitySpinnerLabel = uilabel(app.UIFigure);
            app.qualitySpinnerLabel.Enable = 'off';
            app.qualitySpinnerLabel.Text = 'quality';
            app.qualitySpinner = uispinner(app.UIFigure);
            app.qualitySpinner.Limits = [0 3];
            app.qualitySpinner.Editable = 'off';
            app.qualitySpinner.Enable = 'off';
            app.qualitySpinner.Value = 1;
            app.difficultySpinnerLabel = uilabel(app.UIFigure);
            app.difficultySpinnerLabel.Enable = 'off';
            app.difficultySpinnerLabel.Text = 'difficulty';
            app.difficultySpinner = uispinner(app.UIFigure);
            app.difficultySpinner.Limits = [0 3];
            app.difficultySpinner.Editable = 'off';
            app.difficultySpinner.Enable = 'off';
            app.difficultySpinner.Value = 1;
            app.disableshortcutsCheckBox = uicheckbox(app.UIFigure);
            app.disableshortcutsCheckBox.Text = 'disable shortcuts';
            app.redrawButton = uibutton(app.UIFigure, 'push');
            app.redrawButton.ButtonPushedFcn = createCallbackFcn(app, @redrawButtonPushed, true);
            app.redrawButton.Text = 'redraw';
            app.sizectrlLLabel = uilabel(app.UIFigure);
            app.sizectrlLLabel.Text = 'size ctrl+L';
            app.sizectrlLSpinner = uispinner(app.UIFigure);
            app.sizectrlLSpinner.Limits = [1 1000];
            app.sizectrlLSpinner.Value = 3;
            app.sizeshiftLSpinnerLabel = uilabel(app.UIFigure);
            app.sizeshiftLSpinnerLabel.Text = 'size shift+L';
            app.sizeshiftLSpinner = uispinner(app.UIFigure);
            app.sizeshiftLSpinner.Limits = [1 1000];
            app.sizeshiftLSpinner.Value = 5;
            app.resegmentButton = uibutton(app.UIFigure, 'push');
            app.resegmentButton.ButtonPushedFcn = createCallbackFcn(app, @resegmentButtonPushed, true);
            app.resegmentButton.Text = 'resegment';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.highthSpinnerLabel = uilabel(app.UIFigure);
            app.highthSpinnerLabel.Text = 'high th';
            app.highthSpinner = uispinner(app.UIFigure);
            app.highthSpinner.Step = 0.02;
            app.highthSpinner.Limits = [-5 5];
            app.highthSpinner.Value = 1.5;
            app.naprahovatmistocnnCheckBox = uicheckbox(app.UIFigure);
            app.naprahovatmistocnnCheckBox.ValueChangedFcn = createCallbackFcn(app, @naprahovatmistocnnCheckBoxValueChanged, true);
            app.naprahovatmistocnnCheckBox.Text = 'naprahovat misto cnn';
            app.nucmCheckBox = uicheckbox(app.UIFigure);
            app.nucmCheckBox.ValueChangedFcn = createCallbackFcn(app, @nucmCheckBoxValueChanged, true);
            app.nucmCheckBox.Text = 'nuc (m)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segmentation_tool(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end